classdef kwod_app_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadfileButton                 matlab.ui.control.Button
        OriginalimageLabel             matlab.ui.control.Label
        UIAxes1                        matlab.ui.control.UIAxes
        GotoImagesegmentationButton    matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        ProcessedimageLabel            matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        BrightnessLabel                matlab.ui.control.Label
        ContrastLabel                  matlab.ui.control.Label
        FiltersButtonGroup             matlab.ui.container.ButtonGroup
        MedianButton                   matlab.ui.control.RadioButton
        GaussianButton                 matlab.ui.control.RadioButton
        UnsharpmaskingButton           matlab.ui.control.RadioButton
        FiltersizenxnSpinnerLabel      matlab.ui.control.Label
        FiltersizenxnSpinner           matlab.ui.control.Spinner
        FilterimageButton              matlab.ui.control.Button
        SigmaSpinnerLabel              matlab.ui.control.Label
        SigmaSpinner                   matlab.ui.control.Spinner
        RadiusSpinnerLabel             matlab.ui.control.Label
        RadiusSpinner                  matlab.ui.control.Spinner
        StrengthSpinnerLabel           matlab.ui.control.Label
        StrengthSpinner                matlab.ui.control.Spinner
        RefreshImageButton             matlab.ui.control.Button
        BacktoImagecorrectionButton    matlab.ui.control.Button
        ShowprocessedimagehistogramButton  matlab.ui.control.Button
        LowlimitSpinnerLabel           matlab.ui.control.Label
        LowlimitSpinner                matlab.ui.control.Spinner
        HighlimitSpinnerLabel          matlab.ui.control.Label
        HighlimitSpinner               matlab.ui.control.Spinner
        LowlimitHighlimitLampLabel     matlab.ui.control.Label
        LowlimitHighlimitLamp          matlab.ui.control.Lamp
        ChangecontrastButton           matlab.ui.control.Button
        BinarysegmentationLabel        matlab.ui.control.Label
        DobinarysegmentationButton     matlab.ui.control.Button
        RegiongrowingLabel             matlab.ui.control.Label
        ChooseseedButton               matlab.ui.control.Button
        ThresholdSpinnerLabel          matlab.ui.control.Label
        ThresholdSpinner               matlab.ui.control.Spinner
        DoregiongrowingsegmentationButton  matlab.ui.control.Button
        EdgebasedsegmentationLabel     matlab.ui.control.Label
        SaveImageButton                matlab.ui.control.Button
        ThresholdupperSpinnerLabel     matlab.ui.control.Label
        ThresholdupperSpinner          matlab.ui.control.Spinner
        FiltersButtonGroup_2           matlab.ui.container.ButtonGroup
        CannyButton                    matlab.ui.control.RadioButton
        SobelButton                    matlab.ui.control.RadioButton
        PrewittButton                  matlab.ui.control.RadioButton
        SigmaSpinner_2Label            matlab.ui.control.Label
        SigmaSpinner_2                 matlab.ui.control.Spinner
        DoedgebasedsegmentationButton  matlab.ui.control.Button
        ActivecontourbasedsegmentationLabel  matlab.ui.control.Label
        DoactivecontourbasedsegmentationButton  matlab.ui.control.Button
        NoofiterationsSpinnerLabel     matlab.ui.control.Label
        NoofiterationsSpinner          matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        UIAxes    matlab.ui.control.UIAxes % Description
        ImageFile2 = []; % obraz przetwarzany
        ImageFile = []; % obraz oryginalny
        ImageFile3 = []; % obraz wyświetlany
        fullname = [];
        pixel_x =0;
        pixel_y=0;
    end
    
    methods (Access = private)


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.LoadfileButton.Visible = 'on';
                app.LoadfileButton.Enable = 'on';
                app.RefreshImageButton.Visible = 'on';
                app.RefreshImageButton.Enable = 'off';
                
                % Image processing
                app.BrightnessLabel.Visible = 'on'; % brightness
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'off';
                app.ContrastLabel.Visible = 'on'; % contrast
                app.LowlimitSpinner.Visible = 'on';
                app.HighlimitSpinner.Visible = 'on';
                app.LowlimitHighlimitLamp.Visible = 'on';
                app.ChangecontrastButton.Visible = 'on';
                app.ChangecontrastButton.Enable = 'off';
                app.FiltersButtonGroup.Visible = 'on'; % filtering
                app.FilterimageButton.Enable = 'off';
                app.FilterimageButton.Visible = 'on';
                app.FiltersizenxnSpinner.Enable = 'on';
                app.FiltersizenxnSpinner.Visible = 'on';
                app.FiltersizenxnSpinnerLabel.Visible = 'on';
                app.SigmaSpinner.Enable = 'off';
                app.SigmaSpinner.Visible = 'on';
                app.SigmaSpinnerLabel.Visible = 'on';
                app.RadiusSpinner.Enable = 'off';
                app.RadiusSpinner.Visible = 'on';
                app.RadiusSpinnerLabel.Visible = 'on';
                app.StrengthSpinner.Enable = 'off';
                app.StrengthSpinner.Visible = 'on';
                app.StrengthSpinnerLabel.Visible = 'on';
                
                app.BacktoImagecorrectionButton.Visible = 'off';
                app.GotoImagesegmentationButton.Enable = 'off';
                app.ShowprocessedimagehistogramButton.Enable = 'off';
                app.SaveImageButton.Visible = 'off';
                
                % binary segmentation
                app.BinarysegmentationLabel.Visible = 'off';
                app.DobinarysegmentationButton.Visible = 'off';
                
                % region growing segmentation
                app.RegiongrowingLabel.Visible = 'off';
                app.ChooseseedButton.Visible = 'off';
                app.ThresholdSpinnerLabel.Visible = 'off';
                app.ThresholdSpinner.Visible = 'off';
                app.DoregiongrowingsegmentationButton.Visible = 'off';
                
                % edge-based segmentation
                app.ThresholdupperSpinner.Visible = 'off';
                app.FiltersButtonGroup_2.Visible = 'off';
                app.SigmaSpinner_2Label.Visible = 'off';
                app.SigmaSpinner_2.Visible = 'off';
                app.DoedgebasedsegmentationButton.Visible = 'off';
                app.ThresholdupperSpinnerLabel.Visible = 'off';
                app.EdgebasedsegmentationLabel.Visible = 'off';
                
                % active contour-based segmentation
                app.ActivecontourbasedsegmentationLabel.Visible = 'off';
                app.DoactivecontourbasedsegmentationButton.Visible = 'off';
                app.NoofiterationsSpinner.Visible = 'off';
                app.NoofiterationsSpinnerLabel.Visible = 'off';
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
                close all;
                [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.jpeg'}, 'Select File to Open');
                app.fullname = [filepath, filename];
                if (length(filepath) == 1) && (length(filename) == 1)
                    uiconfirm(app.UIFigure,'No file selected.','Information', 'Icon','warning','Options',{'OK'});
                elseif (contains(filename,'.jpg'))
                    app.ImageFile = imread(app.fullname);
                    imshow(app.ImageFile, 'Parent', app.UIAxes1);
                    app.ImageFile2 = app.ImageFile;
                    app.ImageFile3 = app.ImageFile2;
                    imshow(app.ImageFile2, 'Parent', app.UIAxes2);
                    app.Slider.Enable = 'on';
                    app.FilterimageButton.Enable = 'on';
                    app.GotoImagesegmentationButton.Enable = 'on';
                    app.ShowprocessedimagehistogramButton.Enable = 'on';
                    app.RefreshImageButton.Enable = 'on';
                    app.ChangecontrastButton.Enable = 'on';
                else
                    uiconfirm(app.UIFigure,'Select JPG file.','Information', 'Icon','info','Options',{'OK'});
                end
                
                
        end

        % Button pushed function: GotoImagesegmentationButton
        function GotoImagesegmentationButtonPushed(app, event)
                close all;
                app.LoadfileButton.Visible = 'off';
                app.Slider.Visible = 'off';
                app.BrightnessLabel.Visible = 'off';
                app.FiltersButtonGroup.Visible = 'off';
                app.FiltersizenxnSpinnerLabel.Visible = 'off';
                app.FiltersizenxnSpinner.Visible = 'off';
                app.FilterimageButton.Visible = 'off';
                app.SigmaSpinnerLabel.Visible = 'off';
                app.SigmaSpinner.Visible = 'off';
                app.RadiusSpinnerLabel.Visible = 'off';
                app.RadiusSpinner.Visible = 'off';
                app.StrengthSpinnerLabel.Visible = 'off';
                app.StrengthSpinner.Visible = 'off';
                app.ShowprocessedimagehistogramButton.Visible = 'on';
                app.GotoImagesegmentationButton.Visible = 'off';
                app.BacktoImagecorrectionButton.Visible = 'on';
                app.ChangecontrastButton.Visible = 'off';
                app.ContrastLabel.Visible = 'off';
                app.BinarysegmentationLabel.Visible = 'on';
                app.LowlimitSpinner.Value = 0;
                app.HighlimitSpinner.Value = 255;
                app.DobinarysegmentationButton.Visible = 'on';
                imshow(app.ImageFile3, 'Parent', app.UIAxes2);
                app.SaveImageButton.Visible = 'on';
                app.SaveImageButton.Enable = 'on';
                app.RegiongrowingLabel.Visible = 'on';
                app.ChooseseedButton.Visible = 'on';
                app.ThresholdSpinnerLabel.Visible = 'on';
                app.ThresholdSpinner.Visible = 'on';
                app.DoregiongrowingsegmentationButton.Visible = 'on';
                app.DoregiongrowingsegmentationButton.Enable = 'off';
                app.EdgebasedsegmentationLabel.Visible = 'on';
                app.ThresholdupperSpinner.Visible = 'on';
                app.ThresholdupperSpinnerLabel.Visible = 'on';
                app.FiltersButtonGroup_2.Visible = 'on';
                app.SigmaSpinner_2.Visible ='on';
                app.SigmaSpinner_2Label.Visible = 'on';
                app.DoedgebasedsegmentationButton.Visible = 'on';
                app.ActivecontourbasedsegmentationLabel.Visible = 'on';
                app.DoactivecontourbasedsegmentationButton.Visible = 'on';
                app.NoofiterationsSpinner.Visible = 'on';
                app.NoofiterationsSpinnerLabel.Visible = 'on';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            close all;
            value = app.Slider.Value;
            I2 = app.ImageFile2(:,:,1);
            I3 = app.ImageFile3(:,:,1);
            
            for i = 1:size(I2,1)
                for j = 1:size(I2,2)
                    if I2(i,j)+value>255
                        I3(i,j) = 255;
                    elseif I2(i,j)+value<0
                        I3(i,j) = 0;
                    else
                        I3(i,j) = I2(i,j)+value;
                    end
                end
            end
            app.ImageFile3(:,:,1) = I3;
            app.ImageFile3(:,:,2) = I3;
            app.ImageFile3(:,:,3) = I3;
            
            imshow(app.ImageFile3, 'Parent', app.UIAxes2);
        end

        % Selection changed function: FiltersButtonGroup
        function FiltersButtonGroupSelectionChanged(app, event)
            close all;
            selectedButton = app.FiltersButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Median'
                    app.SigmaSpinner.Enable = 'off';
                    app.RadiusSpinner.Enable = 'off';
                    app.StrengthSpinner.Enable = 'off';
                case 'Gaussian'
                    app.SigmaSpinner.Enable = 'on';
                    app.RadiusSpinner.Enable = 'off';
                    app.StrengthSpinner.Enable = 'off';
                case 'Unsharp masking'
                    app.SigmaSpinner.Enable = 'off';
                    app.RadiusSpinner.Enable = 'on';
                    app.StrengthSpinner.Enable = 'on';
            end
        end

        % Button pushed function: FilterimageButton
        function FilterimageButtonPushed(app, event)
            close all;
            selectedButton = app.FiltersButtonGroup.SelectedObject;
            n = app.FiltersizenxnSpinner.Value;
            sigma = app.SigmaSpinner.Value;
            radius = app.RadiusSpinner.Value;
            amount = app.StrengthSpinner.Value;
            
            switch selectedButton.Text
                case 'Median'
                    app.ImageFile2(:,:,1) = medfilt2(app.ImageFile2(:,:,1),[n n], 'symmetric');
                    app.ImageFile2(:,:,2) = medfilt2(app.ImageFile2(:,:,2),[n n], 'symmetric');
                    app.ImageFile2(:,:,3) = medfilt2(app.ImageFile2(:,:,3),[n n], 'symmetric');
                case 'Gaussian'
                    app.ImageFile2(:,:,1) = imgaussfilt(app.ImageFile2(:,:,1), sigma, 'Padding', 'symmetric', 'FilterSize', n);
                    app.ImageFile2(:,:,2) = imgaussfilt(app.ImageFile2(:,:,2), sigma, 'Padding', 'symmetric', 'FilterSize', n);
                    app.ImageFile2(:,:,3) = imgaussfilt(app.ImageFile2(:,:,3), sigma, 'Padding', 'symmetric', 'FilterSize', n);
                case 'Unsharp masking'
                    app.ImageFile2(:,:,1) = imsharpen(app.ImageFile2(:,:,1), 'Radius', radius, 'Amount', amount);
                    app.ImageFile2(:,:,2) = imsharpen(app.ImageFile2(:,:,2), 'Radius', radius, 'Amount', amount);
                    app.ImageFile2(:,:,3) = imsharpen(app.ImageFile2(:,:,3), 'Radius', radius, 'Amount', amount);
            end
            
            value = app.Slider.Value;
            I2 = app.ImageFile2(:,:,1);
            I3 = app.ImageFile3(:,:,1);
            for i = 1:size(I2,1)
                for j = 1:size(I2,2)
                    if I2(i,j)+value>255
                        I3(i,j) = 255;
                    elseif I2(i,j)+value<0
                        I3(i,j) = 0;
                    else
                        I3(i,j) = I2(i,j)+value;
                    end
                end
            end
            app.ImageFile3(:,:,1) = I3;
            app.ImageFile3(:,:,2) = I3;
            app.ImageFile3(:,:,3) = I3;
            
            imshow(app.ImageFile3, 'Parent', app.UIAxes2);
        end

        % Button pushed function: RefreshImageButton
        function RefreshImageButtonPushed(app, event)
            close all;
            app.ImageFile2 = app.ImageFile;
            app.ImageFile3 = app.ImageFile2;
            app.Slider.Value = 0;
            imshow(app.ImageFile3, 'Parent', app.UIAxes2);
        end

        % Button pushed function: BacktoImagecorrectionButton
        function BacktoImagecorrectionButtonPushed(app, event)
            close all;
            app.LoadfileButton.Visible = 'on';
            app.Slider.Visible = 'on';
            app.BrightnessLabel.Visible = 'on';
            app.FiltersButtonGroup.Visible = 'on';
            app.FiltersizenxnSpinnerLabel.Visible = 'on';
            app.FiltersizenxnSpinner.Visible = 'on';
            app.FilterimageButton.Visible = 'on';
            app.SigmaSpinnerLabel.Visible = 'on';
            app.SigmaSpinner.Visible = 'on';
            app.RadiusSpinnerLabel.Visible = 'on';
            app.RadiusSpinner.Visible = 'on';
            app.StrengthSpinnerLabel.Visible = 'on';
            app.StrengthSpinner.Visible = 'on';
            app.GotoImagesegmentationButton.Visible = 'on';
            app.BacktoImagecorrectionButton.Visible = 'off';
            app.ShowprocessedimagehistogramButton.Visible = 'on';
            app.ContrastLabel.Visible = 'on';
            app.ChangecontrastButton.Visible = 'on';
            app.BinarysegmentationLabel.Visible = 'off';
            app.DobinarysegmentationButton.Visible = 'off';
            app.SaveImageButton.Visible = 'off';
            
            app.RegiongrowingLabel.Visible = 'off';
            app.ChooseseedButton.Visible = 'off';
            app.ThresholdSpinnerLabel.Visible = 'off';
            app.ThresholdSpinner.Visible = 'off';
            app.DoregiongrowingsegmentationButton.Visible = 'off';
            app.EdgebasedsegmentationLabel.Visible = 'off';
            app.ThresholdupperSpinner.Visible = 'off';
            app.ThresholdupperSpinnerLabel.Visible = 'off';
            app.FiltersButtonGroup_2.Visible = 'off';
            app.SigmaSpinner_2.Visible = 'off';
            app.SigmaSpinner_2Label.Visible = 'off';
            app.DoedgebasedsegmentationButton.Visible = 'off';
            app.ActivecontourbasedsegmentationLabel.Visible = 'off';
            app.DoactivecontourbasedsegmentationButton.Visible = 'off';
            app.NoofiterationsSpinner.Visible = 'off';
            app.NoofiterationsSpinnerLabel.Visible = 'off';
        end

        % Button pushed function: ShowprocessedimagehistogramButton
        function ShowprocessedimagehistogramButtonPushed(app, event)
            close all;
            imhist(app.ImageFile3);
            title('Histogram');
            grid on;
            ylabel('Number of pixels');
            hold on;
            plot([app.LowlimitSpinner.Value app.LowlimitSpinner.Value],[0 size(app.ImageFile,2)*100],'Color','g');
            hold on
            plot([app.HighlimitSpinner.Value app.HighlimitSpinner.Value], [0 size(app.ImageFile,2)*100],'Color','r');
            legend('histogram','low limit','high limit');
            hold off;
% ax3 = app.UIAxes3;
% app.ImageFile3=rgb2gray(app.ImageFile3);
% imhist(app.ImageFile3,'Parent',app.UIAxes3);
% axes(ax3);
        end

        % Value changed function: LowlimitSpinner
        function LowlimitSpinnerValueChanged(app, event)
            close all;
            value = app.LowlimitSpinner.Value;
            if value>=app.HighlimitSpinner.Value
                app.LowlimitHighlimitLamp.Color = 'r';
            else
                app.LowlimitHighlimitLamp.Color = 'g';
            end
        end

        % Value changed function: HighlimitSpinner
        function HighlimitSpinnerValueChanged(app, event)
            close all;
            value = app.HighlimitSpinner.Value;
            if value<=app.LowlimitSpinner.Value
                app.LowlimitHighlimitLamp.Color = 'r';
            else
                app.LowlimitHighlimitLamp.Color = 'g';
            end
        end

        % Button pushed function: ChangecontrastButton
        function ChangecontrastButtonPushed(app, event)
            close all;
            if app.HighlimitSpinner.Value<app.LowlimitSpinner.Value
                uiconfirm(app.UIFigure,'High limit has to be greater than low limit.','Information', 'Icon','warning','Options',{'OK'});
            else
                app.ImageFile2 = imadjust(app.ImageFile2,[app.LowlimitSpinner.Value/255 app.HighlimitSpinner.Value/255]);
                app.ImageFile3 = app.ImageFile2;
                imshow(app.ImageFile3, 'Parent', app.UIAxes2);
                app.LowlimitSpinner.Value = 0;
                app.HighlimitSpinner.Value = 255;
            end
        end

        % Button pushed function: DobinarysegmentationButton
        function DobinarysegmentationButtonPushed(app, event)
                I2 = app.ImageFile2(:,:,1);
                I3 = app.ImageFile3(:,:,1);
                if app.HighlimitSpinner.Value<app.LowlimitSpinner.Value
                    uiconfirm(app.UIFigure,'High limit has to be greater than low limit.','Information', 'Icon','warning','Options',{'OK'});
                else
                    for i = 1:size(I2,1)
                        for j = 1:size(I2,2)
                            if I2(i,j)<=app.HighlimitSpinner.Value && I2(i,j)>=app.LowlimitSpinner.Value
                                I3(i,j) = 255;
                            else
                                I3(i,j) = 0;
                            end
                        end
                    end
%                     app.ImageFile3(:,:,1) = I3;
%                     app.ImageFile3(:,:,2) = I3;
%                     app.ImageFile3(:,:,3) = I3;                   
%                     sb = imshow(I3);
                    img = app.ImageFile2;
                    app.ImageFile3 = labeloverlay(img,I3);
                    imshow(app.ImageFile3, 'Parent', app.UIAxes2);
                end
        end

        % Button pushed function: ChooseseedButton
        function ChooseseedButtonPushed(app, event)
            app.DoregiongrowingsegmentationButton.Enable = 'on';    
            h = imshow(app.ImageFile3);
                %             hp = impixelinfo;
                %             set(hp,'Position',[5 1 300 20]);
                [x,y]=ginput(1);
                app.pixel_x=round(x);
                app.pixel_y= round(y);
                pixel_val = app.ImageFile3(app.pixel_x, app.pixel_y);
                close all
                imshow(app.ImageFile3, 'Parent', app.UIAxes2);
                hold(app.UIAxes2,'on');
                plot(app.pixel_x, app.pixel_y, 'r.','Parent', app.UIAxes2);
                hold(app.UIAxes2,'off');
        end

        % Button pushed function: DoregiongrowingsegmentationButton
        function DoregiongrowingsegmentationButtonPushed(app, event)
            t = app.ThresholdSpinner.Value; 
            I = im2double(app.ImageFile3);
            img = app.ImageFile3;
            J = regiongrowing(I,floor(app.pixel_y),floor(app.pixel_x),t);
            img = img + uint8(J)*255;
            app.ImageFile3 = img;
            imshow(app.ImageFile3, 'Parent', app.UIAxes2);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            filter = {'*.jpg';'*.jpeg';'*.png'};
            [file, path] = uiputfile(filter, 'Save File', 'Segmented_Image.jpg');
            filename = fullfile(path,file);
            imwrite(app.ImageFile3,filename);
        end

        % Selection changed function: FiltersButtonGroup_2
        function FiltersButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.FiltersButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'Sobel'
                    app.SigmaSpinner_2.Enable = 'off';
                case 'Canny'
                    app.SigmaSpinner_2.Enable = 'on';
                case 'Prewitt'
                    app.SigmaSpinner_2.Enable = 'off';
            end
            
        end

        % Button pushed function: DoedgebasedsegmentationButton
        function DoedgebasedsegmentationButtonPushed(app, event)
            close all;
            selectedButton = app.FiltersButtonGroup_2.SelectedObject;
            threshold = app.ThresholdupperSpinner.Value;
            sigma = app.SigmaSpinner_2.Value;
            
            switch selectedButton.Text
                case 'Sobel'
                    I = rgb2gray(app.ImageFile3);
                    BW = edge(I,'sobel', threshold);
                    % Detecting the tumor
%                   [~,threshold] = edge(I,'sobel');
%                   fudgeFactor = 0.5;
%                   BW = edge(I,'sobel',threshold * fudgeFactor);
%                   % Dilating the image
%                   se90 = strel('line',3,90);
%                   se0 = strel('line',3,0);
%                   BWsdil = imdilate(BW,[se90 se0]);
%                   app.ImageFile3 = BWsdil;
%                   img = app.ImageFile2;
%                   b = labeloverlay(img,app.ImageFile3);
%                   imshow(b);
%                   [x,y]=ginput(1);
%                   app.pixel_x=round(x);
%                   app.pixel_y= round(y);
%                   BWdfill = imfill(BWsdil,[app.pixel_x app.pixel_y],4);
                    img = app.ImageFile3;
                    app.ImageFile3 = BW;
                    app.ImageFile3 = labeloverlay(img,app.ImageFile3);
                    imshow(app.ImageFile3, 'Parent', app.UIAxes2); 
                case 'Canny'
                    I = rgb2gray(app.ImageFile3);
                    BW = edge(I,'canny',threshold,sigma); 
                    img = app.ImageFile3;
                    app.ImageFile3 = labeloverlay(img,BW);
                    imshow(app.ImageFile3, 'Parent', app.UIAxes2);
                case 'Prewitt'
                    I = rgb2gray(app.ImageFile3);
                    BW = edge(I,'prewitt',threshold);
                    img = app.ImageFile3;
                    app.ImageFile3 = labeloverlay(img,BW);
                    imshow(app.ImageFile3, 'Parent', app.UIAxes2);
            end
            
            imshow(app.ImageFile3, 'Parent', app.UIAxes2);
        end

        % Button pushed function: 
        % DoactivecontourbasedsegmentationButton
        function DoactivecontourbasedsegmentationButtonPushed(app, event)
              iter = app.NoofiterationsSpinner.Value;
              I = rgb2gray(app.ImageFile3);
              waitfor(uiconfirm(app.UIFigure,'Select area to be analyzed.','Area selection', 'Icon','info','Options',{'OK'}));
              imshow(I);
              r = drawrectangle;
              mask =createMask(r);
              close all;
              bw = activecontour(I,mask,iter,'edge');
              app.ImageFile3 = labeloverlay(I,bw);
              imshow(app.ImageFile3, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadfileButton = uibutton(app.UIFigure, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Visible = 'off';
            app.LoadfileButton.Text = 'Load file';
            app.OriginalimageLabel = uilabel(app.UIFigure);
            app.OriginalimageLabel.Text = 'Original image';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTickLabel = '';
            app.UIAxes1.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes1.YTickLabel = '';
            app.GotoImagesegmentationButton = uibutton(app.UIFigure, 'push');
            app.GotoImagesegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @GotoImagesegmentationButtonPushed, true);
            app.GotoImagesegmentationButton.Text = 'Go to Image segmentation >>';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTickLabel = '';
            app.ProcessedimageLabel = uilabel(app.UIFigure);
            app.ProcessedimageLabel.Text = 'Processed image';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-255 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Visible = 'off';
            app.BrightnessLabel = uilabel(app.UIFigure);
            app.BrightnessLabel.Visible = 'off';
            app.BrightnessLabel.Text = 'Brightness:';
            app.ContrastLabel = uilabel(app.UIFigure);
            app.ContrastLabel.Visible = 'off';
            app.ContrastLabel.Text = 'Contrast:';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltersButtonGroupSelectionChanged, true);
            app.FiltersButtonGroup.Title = 'Filters';
            app.FiltersButtonGroup.Visible = 'off';
            app.MedianButton = uiradiobutton(app.FiltersButtonGroup);
            app.MedianButton.Text = 'Median';
            app.MedianButton.Value = true;
            app.GaussianButton = uiradiobutton(app.FiltersButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.UnsharpmaskingButton = uiradiobutton(app.FiltersButtonGroup);
            app.UnsharpmaskingButton.Text = 'Unsharp masking';
            app.FiltersizenxnSpinnerLabel = uilabel(app.UIFigure);
            app.FiltersizenxnSpinnerLabel.Visible = 'off';
            app.FiltersizenxnSpinnerLabel.Text = 'Filter size (n x n)';
            app.FiltersizenxnSpinner = uispinner(app.UIFigure);
            app.FiltersizenxnSpinner.Limits = [3 20];
            app.FiltersizenxnSpinner.Visible = 'off';
            app.FiltersizenxnSpinner.Value = 3;
            app.FilterimageButton = uibutton(app.UIFigure, 'push');
            app.FilterimageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterimageButtonPushed, true);
            app.FilterimageButton.Visible = 'off';
            app.FilterimageButton.Text = 'Filter image';
            app.SigmaSpinnerLabel = uilabel(app.UIFigure);
            app.SigmaSpinnerLabel.Visible = 'off';
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.UIFigure);
            app.SigmaSpinner.Step = 0.1;
            app.SigmaSpinner.Limits = [0 10];
            app.SigmaSpinner.Visible = 'off';
            app.SigmaSpinner.Value = 0.5;
            app.RadiusSpinnerLabel = uilabel(app.UIFigure);
            app.RadiusSpinnerLabel.Visible = 'off';
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.UIFigure);
            app.RadiusSpinner.Step = 0.5;
            app.RadiusSpinner.Limits = [0 10];
            app.RadiusSpinner.Visible = 'off';
            app.RadiusSpinner.Value = 1;
            app.StrengthSpinnerLabel = uilabel(app.UIFigure);
            app.StrengthSpinnerLabel.Visible = 'off';
            app.StrengthSpinnerLabel.Text = 'Strength';
            app.StrengthSpinner = uispinner(app.UIFigure);
            app.StrengthSpinner.Step = 0.1;
            app.StrengthSpinner.Limits = [0 2];
            app.StrengthSpinner.Visible = 'off';
            app.StrengthSpinner.Value = 0.8;
            app.RefreshImageButton = uibutton(app.UIFigure, 'push');
            app.RefreshImageButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshImageButtonPushed, true);
            app.RefreshImageButton.Text = 'Refresh Image';
            app.BacktoImagecorrectionButton = uibutton(app.UIFigure, 'push');
            app.BacktoImagecorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoImagecorrectionButtonPushed, true);
            app.BacktoImagecorrectionButton.Visible = 'off';
            app.BacktoImagecorrectionButton.Text = '<< Back to Image correction';
            app.ShowprocessedimagehistogramButton = uibutton(app.UIFigure, 'push');
            app.ShowprocessedimagehistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprocessedimagehistogramButtonPushed, true);
            app.ShowprocessedimagehistogramButton.Text = 'Show processed image histogram';
            app.LowlimitSpinnerLabel = uilabel(app.UIFigure);
            app.LowlimitSpinnerLabel.Text = 'Low limit';
            app.LowlimitSpinner = uispinner(app.UIFigure);
            app.LowlimitSpinner.Limits = [0 255];
            app.LowlimitSpinner.ValueChangedFcn = createCallbackFcn(app, @LowlimitSpinnerValueChanged, true);
            app.HighlimitSpinnerLabel = uilabel(app.UIFigure);
            app.HighlimitSpinnerLabel.Text = 'High limit';
            app.HighlimitSpinner = uispinner(app.UIFigure);
            app.HighlimitSpinner.Limits = [0 255];
            app.HighlimitSpinner.ValueChangedFcn = createCallbackFcn(app, @HighlimitSpinnerValueChanged, true);
            app.HighlimitSpinner.Value = 255;
            app.LowlimitHighlimitLampLabel = uilabel(app.UIFigure);
            app.LowlimitHighlimitLampLabel.Text = 'Low limit < High limit';
            app.LowlimitHighlimitLamp = uilamp(app.UIFigure);
            app.ChangecontrastButton = uibutton(app.UIFigure, 'push');
            app.ChangecontrastButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecontrastButtonPushed, true);
            app.ChangecontrastButton.Text = 'Change contrast';
            app.BinarysegmentationLabel = uilabel(app.UIFigure);
            app.BinarysegmentationLabel.Text = 'Binary segmentation:';
            app.DobinarysegmentationButton = uibutton(app.UIFigure, 'push');
            app.DobinarysegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @DobinarysegmentationButtonPushed, true);
            app.DobinarysegmentationButton.Text = 'Do binary segmentation';
            app.RegiongrowingLabel = uilabel(app.UIFigure);
            app.RegiongrowingLabel.Text = 'Region growing:';
            app.ChooseseedButton = uibutton(app.UIFigure, 'push');
            app.ChooseseedButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseseedButtonPushed, true);
            app.ChooseseedButton.Text = 'Choose seed';
            app.ThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.UIFigure);
            app.ThresholdSpinner.Step = 0.01;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.Value = 0.2;
            app.DoregiongrowingsegmentationButton = uibutton(app.UIFigure, 'push');
            app.DoregiongrowingsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @DoregiongrowingsegmentationButtonPushed, true);
            app.DoregiongrowingsegmentationButton.Text = 'Do region growing segmentation';
            app.EdgebasedsegmentationLabel = uilabel(app.UIFigure);
            app.EdgebasedsegmentationLabel.Text = 'Edge-based segmentation:';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ThresholdupperSpinnerLabel = uilabel(app.UIFigure);
            app.ThresholdupperSpinnerLabel.Text = 'Threshold (upper)';
            app.ThresholdupperSpinner = uispinner(app.UIFigure);
            app.ThresholdupperSpinner.Step = 0.01;
            app.ThresholdupperSpinner.Limits = [0 1];
            app.ThresholdupperSpinner.Value = 0.1;
            app.FiltersButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @FiltersButtonGroup_2SelectionChanged, true);
            app.FiltersButtonGroup_2.Title = 'Filters';
            app.CannyButton = uiradiobutton(app.FiltersButtonGroup_2);
            app.CannyButton.Text = 'Canny';
            app.CannyButton.Value = true;
            app.SobelButton = uiradiobutton(app.FiltersButtonGroup_2);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uiradiobutton(app.FiltersButtonGroup_2);
            app.PrewittButton.Text = 'Prewitt';
            app.SigmaSpinner_2Label = uilabel(app.UIFigure);
            app.SigmaSpinner_2Label.Text = 'Sigma';
            app.SigmaSpinner_2 = uispinner(app.UIFigure);
            app.SigmaSpinner_2.Step = 0.1;
            app.SigmaSpinner_2.Limits = [0 Inf];
            app.SigmaSpinner_2.Value = 1.4;
            app.DoedgebasedsegmentationButton = uibutton(app.UIFigure, 'push');
            app.DoedgebasedsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @DoedgebasedsegmentationButtonPushed, true);
            app.DoedgebasedsegmentationButton.Text = 'Do edge-based segmentation';
            app.ActivecontourbasedsegmentationLabel = uilabel(app.UIFigure);
            app.ActivecontourbasedsegmentationLabel.Text = 'Active contour - based segmentation:';
            app.DoactivecontourbasedsegmentationButton = uibutton(app.UIFigure, 'push');
            app.DoactivecontourbasedsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @DoactivecontourbasedsegmentationButtonPushed, true);
            app.DoactivecontourbasedsegmentationButton.Text = 'Do active contour-based segmentation';
            app.NoofiterationsSpinnerLabel = uilabel(app.UIFigure);
            app.NoofiterationsSpinnerLabel.Text = 'No. of iterations';
            app.NoofiterationsSpinner = uispinner(app.UIFigure);
            app.NoofiterationsSpinner.Limits = [0 1500];
            app.NoofiterationsSpinner.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kwod_app_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end